classdef DeepEdge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Mn_LoadVideo                   matlab.ui.container.Menu
        Mn_LoadContours                matlab.ui.container.Menu
        Mn_LoadModel                   matlab.ui.container.Menu
        Mn_LoadTextGrid                matlab.ui.container.Menu
        LoadvideocontoursMenu          matlab.ui.container.Menu
        LoadvideoTextGridMenu          matlab.ui.container.Menu
        LoadvideocontoursTextGridMenu  matlab.ui.container.Menu
        Mn_Export                      matlab.ui.container.Menu
        Mn_ExportCurrentFrame          matlab.ui.container.Menu
        Mn_ExpImgRaw                   matlab.ui.container.Menu
        Mn_ExpImgCrop                  matlab.ui.container.Menu
        Mn_ExpFrmAnchors               matlab.ui.container.Menu
        Mn_ExpFrmAnchorsOri            matlab.ui.container.Menu
        Mn_ExpFrmAnchorsCrop           matlab.ui.container.Menu
        Mn_ExportMat                   matlab.ui.container.Menu
        ExportcroppingparamtersMenu    matlab.ui.container.Menu
        ControlMenu                    matlab.ui.container.Menu
        Mn_Previousframe               matlab.ui.container.Menu
        Mn_Nextframe                   matlab.ui.container.Menu
        Mn_ApplyCurrentFrame           matlab.ui.container.Menu
        Mn_Track                       matlab.ui.container.Menu
        Mn_StopTracking                matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        Mn_VideoInfo                   matlab.ui.container.Menu
        Mn_ModelInfo                   matlab.ui.container.Menu
        Mn_AboutDeepEdge               matlab.ui.container.Menu
        Ed_TotalTime                   matlab.ui.control.NumericEditField
        Ed_TotalFrames                 matlab.ui.control.EditField
        Msg1                           matlab.ui.control.TextArea
        Ed_Time                        matlab.ui.control.NumericEditField
        TimesLabel                     matlab.ui.control.Label
        Ed_CursorLoc                   matlab.ui.control.EditField
        Tabs                           matlab.ui.container.TabGroup
        Tab_Image                      matlab.ui.container.Tab
        CropParaHelpMsg                matlab.ui.control.EditField
        Sw_ifInvert                    matlab.ui.control.Switch
        Bn_CropButton                  matlab.ui.control.StateButton
        Bn_Uncrop                      matlab.ui.control.Button
        Sw_ifGray                      matlab.ui.control.Switch
        Ed_CropRect                    matlab.ui.control.EditField
        AnchorTab                      matlab.ui.container.Tab
        UIAnchorTable                  matlab.ui.control.Table
        Bn_DelAnchors                  matlab.ui.control.Button
        Slid_AnchorAlpha               matlab.ui.control.Slider
        OpacityLabel                   matlab.ui.control.Label
        Slid_AnchorSize                matlab.ui.control.Slider
        AnchorsizeSliderLabel          matlab.ui.control.Label
        Sw_ClickAnchor                 matlab.ui.control.Switch
        ClickanchorsSwitchLabel        matlab.ui.control.Label
        DeepEdgeTab                    matlab.ui.container.Tab
        DetectMethodGroup              matlab.ui.container.ButtonGroup
        Ck_Snake                       matlab.ui.control.RadioButton
        Ck_NN                          matlab.ui.control.RadioButton
        Ed_MaskSigma                   matlab.ui.control.NumericEditField
        MasksigmaEditFieldLabel        matlab.ui.control.Label
        Ck_UseMaskFromPreviousFrame    matlab.ui.control.CheckBox
        Bn_Track                       matlab.ui.control.StateButton
        Bn_StopTrack                   matlab.ui.control.StateButton
        Bn_ApplyCurrentFrame           matlab.ui.control.Button
        Pn_FrameControl                matlab.ui.container.Panel
        Ed_GoToN                       matlab.ui.control.NumericEditField
        Bn_GoTo                        matlab.ui.control.Button
        Ed_PreviousN                   matlab.ui.control.NumericEditField
        Ed_NextN                       matlab.ui.control.NumericEditField
        Bn_PreviousNFrame              matlab.ui.control.Button
        Bn_LastFrame                   matlab.ui.control.Button
        Bn_NextNFrame                  matlab.ui.control.Button
        Bn_NextFrame                   matlab.ui.control.Button
        Bn_PreviousFrame               matlab.ui.control.Button
        Bn_FirstFrame                  matlab.ui.control.Button
        Ed_Frame                       matlab.ui.control.NumericEditField
        FrameEditFieldLabel            matlab.ui.control.Label
        Ax1_AudioAll                   matlab.ui.control.UIAxes
        Ax0                            matlab.ui.control.UIAxes
    end

%% DeepEdge : 
% Automatic ultrasound tongue contouring 
% combining a deep neural network and an edge detection algorithm
% 
% Copyright (C) 2020 Wei-Rong Chen <chenw@haskins.yale.edu>
% This program is free software under GNU General Public License, version 3.
% This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. 
% See the GNU General Public License for more details.
% wei-rong chen
% Lastest update: wrc 21MAR2022 v1.6.7
    
    properties (Access = private)

        Version = '1.6.7'; Update = '21MAR2022';  % Update version

        active = false; % set active to true when video is loaded.
        handle_cropRect = []; % handle for the seleted cropping rectangle
        CurrentImgObj = []; %current image object
        flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
        flag_SelectCropRectWindowButtonDown = false; % flag to indicate mouse button down for SelectCropRect
        handle_xy = []; handle_anchors = []; 
        appPath = fileparts(mfilename('fullpath'));
    end
    properties (Access = public)
        vObj; % video object
        crop_rect = [1, 1, 0, 0];% cropping rectangle: [xmin ymin width height]
        ANCHORS = []; % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
        ENERGY = []; XY = []; TIME = []; METHOD = []; 
        anchorTableArray = [];
        anchor_size = 45;  anchor_alpha= 0.4; nXYpoints = 100;
        anchor_color = [1,0,0]; % system anchor color = red
        user_anchor_color = [0,0,1]; % system anchor color = blue
        ifGray = false; ifInvert = false; 
        frame_list = []; modelname = []; net = []; modelPath = [];
        img = []; % original image of current frame
        height=[]; width=[]; duration=[]; FrameRate = []; nFrames = [];
        selectedAnchorID = []; mask = []; hg_mask_boundary = [];
        defaultMsg = []; AUDIO=[]; AudioCursorHandle =[]; TextGridTier =[];
        PARAMS = []; % Collection of parameters
    end
    methods (Access = private)
        %%
        function initialize(app)
            % Output results:
            app.ANCHORS = []; % ANCHORS{iFrame} : n x 3 {x, y, isUserInput} 
            app.anchorTableArray = NaN(1,2); 
            app.UIAnchorTable.Data = app.anchorTableArray;
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.XY = []; app.ENERGY = []; app.TIME = []; app.METHOD = [];
            % VIDEO:
            app.vObj = []; 
            % AUDIO:
            app.AUDIO = []; app.PARAMS.AudioResampleRate = 10000; 
            app.PARAMS.AudioAllColor = [0.5, 0.5, 0.5]; % Audio display color: rgb('gray')
            app.PARAMS.AudioCursorColor = [.5, 0, .5]; % Purple = [.5, 0, .5];
            app.TextGridTier = []; % TextGridTier.segs: timestamps;  TextGridTier.labs: labels
            app.PARAMS.TextGridColor = [0, 0.39, 0]; % rgb('darkgreen')
            app.PARAMS.TextGridFontSize = 9;
            % Deactivate: 
            app.active = false; % set active to true when video is loaded.
            % Crop setting:
            app.crop_rect = [1, 1, 0, 0];% cropping rectangle: [xmin ymin width height]
            app.flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
            app.flag_SelectCropRectWindowButtonDown = false; % flag to indicate mouse button down for SelectCropRect
            rect = app.crop_rect; 
            app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
            % Ax0: Main image axes
            app.Ax0.Visible = 'off'; cla(app.Ax0, 'reset'); 
            app.Ax0.Interactions = []; app.Ax0.Box = 'on'; app.Ax0.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Ax0)
            disableDefaultInteractivity(app.Ax1_AudioAll)
            %% UserData for frame changing buttons specify 'how many frame to move forward/backward'
            app.Bn_NextFrame.UserData = 1; app.Bn_PreviousFrame.UserData = -1; 
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
            app.Bn_FirstFrame.UserData = -inf; app.Bn_LastFrame.UserData = inf; 
            app.Ed_GoToN.Value = 1; app.Bn_GoTo.UserData = 1;
            %%
            app.Sw_ClickAnchor.Value = 'On';
            app.img = []; % original image of current frame
            app.anchor_size = 45;  app.anchor_alpha= 0.4; app.nXYpoints = 100;
            app.anchor_color = [1,0,0]; % system anchor color = red
            app.user_anchor_color = [0,0,1]; % system anchor color = blue
            app.Slid_AnchorSize.Value = 25; app.Slid_AnchorAlpha.Value=70;
            app.ifGray = false; app.ifInvert = false;
            app.selectedAnchorID = []; app.mask = []; 
            %% 
            app.Mn_ExpImgRaw.UserData.Crop=false; app.Mn_ExpImgCrop.UserData.Crop=true;
            app.Mn_ExpFrmAnchorsOri.UserData.Crop = false; app.Mn_ExpFrmAnchorsCrop.UserData.Crop = true;
            % Graphic handles: 
            app.CurrentImgObj = []; %current image object
            app.handle_cropRect = []; % handle for the seleted cropping rectangle 
            app.hg_mask_boundary=[];% handle group of two animatedlines
            app.defaultMsg = '';
        end % function initialize
        %%
        function [fname, selectionSuccess] = SelectVideoFileUI(app)
            fname=[];
            % Display uigetfile dialog
            filterspec = {'*.avi;*.mp4;*.wmv;*.mov','All Video Files'};
            [fn, p] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  selectionSuccess = false; return; end 
            fname = fullfile(p, fn);
            [~,f, ~] = fileparts(fn);
            selectionSuccess = true;
            if ~isvarname(f)
                msg =sprintf(['"%s" is not a valid variable name in GetContours.' ...
                    ' Skipping this error will result in the exported .mat file ' ...
                    'incompatible with GetContours. \n\n' ...
                    'A valid variable name starts with a letter, followed by letters, digits, or underscores'], f);
                cancelTxt = 'Cancel'; continueTxt = 'Ignore error & continue';
                title = 'The file name is incompatible with GetContours';
                selection = uiconfirm(app.UIFigure, msg, title, 'Options', {cancelTxt, continueTxt}, 'DefaultOption', 1, 'CancelOption', 1, 'Icon','error');
                if strcmp(selection, cancelTxt), selectionSuccess = false; return;end
            end
        end
        %%
        function LoadVideoActivateApp(app,FName)
           %UIFigure.Pointer is supported after R2020a (ver9.8)
           if ~verLessThan('matlab','9.8') 
               set(app.UIFigure, 'pointer','watch'); 
           end
            cla(app.Ax1_AudioAll); % Clear axis
            try 
                app.vObj = VideoReader(FName); 
            catch 
                uialert(app.UIFigure,'Cannot read the video file.','Read error');
                return
            end
            try
                [s, sr, Dur] = app.ImportAudio_simple(FName, app.PARAMS.AudioResampleRate);
                x = linspace(0, Dur, length(s)); 
                h=plot(app.Ax1_AudioAll, x, s, 'color', app.PARAMS.AudioAllColor,'HitTest','off'); %#ok<NASGU> 
                set(app.Ax1_AudioAll, 'XLim', [0,Dur]); 
                app.AUDIO.s = s; app.AUDIO.sr = sr; app.AUDIO.Dur = Dur;
                app.AudioCursorHandle = line(app.Ax1_AudioAll, [0,0], [-1,1], 'color', app.PARAMS.AudioCursorColor); 
            catch 
                app.AUDIO = [];
            end
            app.active = true; % Activate the App
            axis(app.Ax0, 'image');
            app.Ax0.XLim = [1,app.vObj.Width]; app.Ax0.YLim = [1,app.vObj.Height]; 
            app.nFrames = app.vObj.NumFrames; app.Ed_TotalFrames.Value = ['/ ' num2str(app.nFrames)]; 
            app.Ed_TotalTime.Value = app.vObj.Duration; 
            app.ANCHORS = cell(app.nFrames,1); app.XY = cell(app.nFrames,1); 
            app.ENERGY = cell(app.nFrames,1); app.TIME = cell(app.nFrames,1); app.METHOD = cell(app.nFrames,1); 
            app.frame_list = 1:app.nFrames;
            app.height = app.vObj.Height; app.width = app.vObj.Width; app.duration = app.vObj.Duration;
            app.crop_rect = [1,1,app.width, app.height]; updateStoredCropRect(app, app.crop_rect);
           %UIFigure.Pointer is supported after R2020a (ver9.8)
           if ~verLessThan('matlab','9.8') 
               set(app.UIFigure, 'pointer','arrow'); 
           end
        end %LoadVideoActivateApp(app,FName)
        %%
        function dispFrame(app, frame)
            if frame < 1 || frame > app.nFrames,return;end
            currentFrame = app.Ed_Frame.Value; 
            app.Ed_Frame.Value = frame; 
            try im= read(app.vObj,frame); catch, app.Ed_Frame.Value = currentFrame; return; end
            if strcmpi(app.Sw_ifGray.Value,'Gray'), app.ifGray = true; else, app.ifGray = false; end
            app.img = im;
            if app.ifGray, im = uint8(mean(im,3));  colormap(app.Ax0, gray);end
            if app.ifInvert, im = imcomplement(im); end
            if isempty(app.CurrentImgObj)
                app.CurrentImgObj = imagesc(app.Ax0, im); 
                app.Ax0.Toolbar.Visible = 'off'; % turn off again because 'imagesc' turns on Ax0.Toolbar
            else
                app.CurrentImgObj.CData = im; 
            end
            app.Ed_Frame.Value = frame; app.Ed_Time.Value = (frame-1)/app.vObj.FrameRate;%
%             v(vi).TIME = (v(vi).FRAME-1)/sr;	
            CurrentFrameTime = (frame-1)/app.vObj.FrameRate;
            app.TIME{frame} = CurrentFrameTime;
            maskSigmaP = app.Ed_MaskSigma.Value /100;
            sigma = mean(app.crop_rect(3:4))*maskSigmaP;
            hold(app.Ax0,'on');
            if app.Ck_UseMaskFromPreviousFrame.Value
                if frame > 1 && ~isempty(app.ANCHORS{frame-1})
                    anchor_previous_frame = app.sortAnchors(app.ANCHORS{frame-1}); 
                    [~,app.mask] = app.GenMaskFromAnchors(anchor_previous_frame(:,1:2), ...
                        sigma, app.width, app.height); 
                else
                    app.mask = ones(app.height, app.width);
                end
            else
                app.mask = ones(app.height, app.width); 
            end
            if isempty(app.hg_mask_boundary)
                app.hg_mask_boundary = app.DisplayMaskBoundary(app.Ax0, app.mask);
            else
                app.hg_mask_boundary = app.UpdateMaskBoundary(app.mask,app.hg_mask_boundary);
            end
             hold(app.Ax0,'off');
             if ~isempty(app.AUDIO) && isgraphics(app.AudioCursorHandle)
                 app.AudioCursorHandle.XData = [CurrentFrameTime,CurrentFrameTime];
             end
%              drawnow;
        end % dispFrame(app, frame)
        %%
        function app = updateStoredCropRect(app, rect)
            app.crop_rect = rect; app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
        end %function app = updateStoredCropRect(app, rect)
        %%
        function app = updatePlotAnchors(app)
            frame = app.Ed_Frame.Value; 
            app.Msg1.Value = app.defaultMsg; 
            if isempty(app.ANCHORS{frame})
                if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
                if isgraphics(app.handle_xy), delete(app.handle_xy);end
                %% Update anchor table:
                app.anchorTableArray = NaN(1,2);
                app.UIAnchorTable.Data = app.anchorTableArray;
                return;
            end
            anchors = app.ANCHORS{frame}; xy = app.XY{frame};
            x = anchors(:,1); y = anchors(:,2); isUser = anchors(:,3); %n = size(anchors,1);
            sz = app.anchor_size; 
            ancColor =  isUser*app.user_anchor_color + ~isUser*app.anchor_color;
            hold(app.Ax0,'on');
            if isempty(app.handle_anchors) || ~isgraphics(app.handle_anchors)
               % First time plotting anchor points:
                app.handle_anchors = scatter(app.Ax0,x,y, sz, ancColor, 'filled', 'o', 'MarkerFaceAlpha', app.anchor_alpha,'Visible', 'on');
            else
% %               % Update existing plotted anchors:
                app.handle_anchors.XData = x; app.handle_anchors.YData = y; 
                app.handle_anchors.SizeData = app.anchor_size;
                app.handle_anchors.CData = ancColor;
                app.handle_anchors.MarkerFaceAlpha = app.anchor_alpha;
            end
            if isempty(app.handle_xy) || ~isgraphics(app.handle_xy)
                % First time plotting XY connecting line:
                app.handle_xy = plot(app.Ax0, xy(:,1), xy(:,2), 'color', 'y', 'visible', 'on', 'linestyle', ':', 'LineWidth', 1);
                app.handle_xy.Color(4) = 0.7;
            else
% %               % Update existing XY connecting line:
                app.handle_xy.XData = xy(:,1); app.handle_xy.YData = xy(:,2);                
            end
            drawnow 
            hold(app.Ax0,'off');
            %% Update anchor table:
            x = round(x); y = round(y);
            app.anchorTableArray = [x,y];
            app.UIAnchorTable.Data = app.anchorTableArray;
        end %function app=updatePlotAnchors(app)
        %%
        function app = do_uncrop(app)
            app = updateStoredCropRect(app, [1,1,app.width,app.height]);
            app.Ax0.XLim = [1,app.width]; app.Ax0.YLim = [1,app.height]; 
        end %function app=do_uncrop(app)
        %%
        function app = do_crop(app)
            if isempty(app.handle_cropRect), return;end
            rect = app.get_rect_from_plot_handle(app.handle_cropRect);
            app = updateStoredCropRect(app, rect);
            delete(app.handle_cropRect); app.handle_cropRect=[];
            app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1]; 
        end %function app=do_crop(app)
        %%
        function app = LoadContourStructUI(app)
            [f, p] = uigetfile('*.mat','Select contour file ...');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(f,0),  return; end 
            matFName = fullfile(p, f);  
            app=LoadContourStructFromMatFile(app, matFName);
        end  %function app =LoadContourStructUI(app)
        %%
        function app = LoadContourStructFromMatFile(app, matFName)
            m = app.load1var(matFName);
            % Check if it is correct struct format:
            if ~isstruct(m) || ~app.hasfield(m,'XY') || ~app.hasfield(m,'ANCHORS') || ~app.hasfield(m,'FRAME') || ~app.hasfield(m,'TIME') || numel(m) > app.nFrames
                uialert(app.UIFigure,'Invalid contour format.','Read contour error'); return
            end
            if ~all(cellfun(@isempty, app.ANCHORS))
                selection = uiconfirm(app.UIFigure, 'Erase all & then load, or Append', 'How to deal with current contours','Options',{'Erase all & Load', 'Append', 'Cancel'}, 'DefaultOption',1,'CancelOption',3);  
                 if strcmpi(selection, 'Erase all & Load'), app= EraseContoursAllFrames(app); end
                if strcmpi(selection, 'Cancel'), return;  end
            end
           %%
            % Import contours frame by frame
            for i = 1:numel(m)
                k = m(i).FRAME; % k=frame number
                anchors = m(i).ANCHORS; 
                anchors(:,3) = 0; % 3rd column is 'IsUser' input.
                app.ANCHORS{k} =  anchors;
                app.XY{k} = m(i).XY; 
                if app.hasfield(m,'TRKRES') && ~isempty(m(i).TRKRES) 
                    if app.hasfield(m(i).TRKRES,'ENERGY') && ~isempty(m(i).TRKRES.ENERGY)
                        app.ENERGY{k} =m(i).TRKRES.ENERGY;
                     end
                    if app.hasfield(m(i).TRKRES,'METHOD') && ~isempty(m(i).TRKRES.METHOD)
                        app.METHOD{k} =m(i).TRKRES.METHOD;
                    end
                end
            end 
            updatePlotAnchors(app);            
        end %function app =LoadContourStructFromMatFile(app)
        %%
        function app= EraseContoursAllFrames(app)
            app.ANCHORS = cell(app.nFrames,1); app.XY = cell(app.nFrames,1);  
            app.anchorTableArray = NaN(1,2);
            app.UIAnchorTable.Data = app.anchorTableArray;
            app.ENERGY = cell(app.nFrames,1); app.TIME = cell(app.nFrames,1);  app.METHOD = cell(app.nFrames,1); 
            updatePlotAnchors(app);  
        end %function app= EraseContoursAllFrames(app)
        %%
        function [x,y, t_audio] = update_axes_cursor_loc(app)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1_AudioAll.Position; % Axis for AudioAll
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %current x,y positions in Ax0.
            t_audio = [];
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                app.Ed_CursorLoc.Value = sprintf('%0.0f, %0.0f', x, y);
            elseif app.ifWithinPos(fig_pt, pos_Ax1) && ~isempty(app.AUDIO)
                % If the cursor is within Ax1_AudioAll, update the
                % CursorLoc value to audio time.
                t_audio = app.Ax1_AudioAll.CurrentPoint(1,1); % Cursor current time in audio
                app.Ed_CursorLoc.Value = sprintf('%0.3f', t_audio);
            else
                app.Ed_CursorLoc.Value = '';
            end
        end %function [x,y] = update_axes_cursor_loc(app)
        %%
        function [x,y] = axes_cursor_loc(app)
            temp = app.Ax0.CurrentPoint; ax_pt = [temp(1,1) temp(1,2)]; % ax_pt : current x,y positions in Ax0.
            x = round(ax_pt(1)); y = round(ax_pt(2));
        end %function [x,y] = axes_cursor_loc(app)  
        %%
        function [anchors, snakeEnergy, xy] = detectOneFrame(app, im, method)
            % 'anchors' : n x 3 {x, y, isUserInput}  % the x-y coordinates in the stored "anchors" are with respect to the original (uncropped) image. 
            % crop_rect: % cropping rectangle: [xmin ymin width height]
            %%   SNAKE parameters: 
            sigma= 5; Delta=2;  BandPenalty = 2;  Alpha = 0.7; Lambda1 = 0.7; UseBand =1; ROI = [];
            snakeEnergy = NaN;
            %%
            h0 = size(im,1); w0 = size(im,2);
            if isempty(app.mask), app.mask = ones(h0, w0); end
            im = rgb2gray(im);% if color, convert im to gray scale
            im = im2double(im);   
            mask1 = imgaussfilt( im2double(app.mask),10);  mask1 =  mask1 ./ max(mask1(:));
            mask1_cropped = imcrop(mask1, app.crop_rect);
            im_cropped = imcrop(im, app.crop_rect); 
%             mask_cropped = imcrop(app.mask, app.crop_rect);
            cropPt = app.crop_rect(1:2); % Upper-leftmost point (xmin, ymin) in cropping parameters
            nPt = 20; % how many points for interpolation
            im_masked1 = im2uint8(im_cropped.*mask1_cropped);
            im_cropped = im2uint8(im_cropped);
            [nnXY, tMask, probMask,  nnXYlength, anchors] = predUSTC(app.net,im_cropped,nPt);
            if isempty(anchors), snakeEnergy=[];xy=[];return; end
            anchors = app.sortAnchors(anchors); 
            if strcmpi(method, 'NN')
                anchors = [anchors  + cropPt, zeros(nPt,1)];
            elseif strcmpi(method, 'NNSnake')
                if isempty(anchors), snakeEnergy=[]; xy = []; return; end
                    [snakeXY, snakeEnergy]=slurp_snake(im_masked1 , anchors, [], nPt, sigma, Delta, BandPenalty, Alpha, Lambda1, ROI, [], UseBand);
                snakeXY = app.sortAnchors(snakeXY);
                anchors = [snakeXY + cropPt, zeros(nPt,1)];
            end
            xy = app.interpLine(anchors(:,1:2), app.nXYpoints, 'makima');
            % Round up: 
            xy = round(xy, 2); anchors = round(anchors, 2);
        end % function [anchors, snakeEnergy, xy] = detectOneFrame
        %
        function loadModel(app, FName)
            % function pragma to load toolboxes:
            %#function nnet.cnn.layer.PixelClassificationLayer
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading model','Indeterminate','on');
            mat = app.load1var(FName,'net');
            close(UIprogressDialog_handle)
            if ~isa(mat, 'DAGNetwork')
                uialert(app.UIFigure,'Incorrect model','Error loading model'); 
                return            
            end
            [p,f,~]=fileparts(FName); app.net = mat; app.modelname = f; app.modelPath = p; 
        end
        %%
        function loadTextGrid(app, fname)
            try
                [TG, TierNames] = app.ReadTextGrid(fname); 
            catch 
                uialert(app.UIFigure,'Invalid TextGrid','Error reading TextGrid'); 
                return
            end

            if numel(TierNames) > 1
                [ind,ifSelected] = listdlg('PromptString',{'Select only one tier ',...
                 'in the TextGrid',''},'SelectionMode','single','ListString',TierNames);
            else 
                % If there is only one tier, then just select the tier without asking. 
                ind = 1; ifSelected = true;
            end
            figure(app.UIFigure) % Get focus back on UIFigure
            if ~ifSelected, return; end
            app.TextGridTier = TG(ind);
        end
        %%
        function drawTextGridIntervals(app)
            if isempty(app.TextGridTier), return; end
            h = findobj(app.Ax1_AudioAll,'tag', 'TextGrid');
            if ~isempty(h), delete(h); end
            tg = app.TextGridTier;  labs = tg.labs;
            y = 1; 
            alpha = 0.6;
            for i = 1:numel(labs)
                lab = labs{i}; t1 = tg.segs(i,1); 
                h1 = line(app.Ax1_AudioAll, [t1,t1], [0,1], 'color', app.PARAMS.TextGridColor, 'linestyle', '-', 'linewidth', 1, 'tag', 'TextGrid'); 
                h1.Color(4) = alpha;
                if size(tg.segs,2) > 1 % interval tier
                    t = mean(tg.segs(i,:));
                else % point tier
                    t = tg.segs(i);
                end
                text(app.Ax1_AudioAll, t, y, lab, 'fontsize', app.PARAMS.TextGridFontSize, ...
                    'horizontalalignment', 'center', 'tag', 'TextGrid','color', 'k','VerticalAlignment','bottom', 'interpreter', 'none');
            end
        end %drawTextGridIntervals(app)
    end % method (Access=private)
    
    %% Below are supporting functions
    methods (Access = public, Static=true)
        %
        function Export2Mat(fig, outFN, ANCHORS, XY, ENERGY, METHOD, TIME)
            nFrames = numel(ANCHORS);
            FRAME = num2cell((1:nFrames)');
            TRKRES = cellfun(@(x, y) struct('ENERGY', x, 'NPARTICLES', [], 'METHOD', y), ENERGY, METHOD, 'uniformoutput', false);
             % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
            ANCHORS = cellfun(@rm3rdCol, ANCHORS, 'UniformOutput', false); % Remove the 3rd column in anchors 
            XY = cellfun(@rm3rdCol, XY, 'UniformOutput', false); % Remove the 3rd column in anchors 
            contours = struct('XY', XY, 'ANCHORS', ANCHORS, 'FRAME', FRAME, 'NOTE', [], ...
                'TRKRES',TRKRES, 'TIME', TIME, 'IMAGE', []);
            contours(cellfun(@isempty,{contours.XY})) = [];					% remove empty frames
             [p,f,e]=fileparts(outFN);  if isempty(e), outFN = fullfile(p, [f '.mat']); end
             if ~isvarname(f), varN = 'contours'; else, varN = f; end
             tmpStruct.(varN) = contours;
            % If output file exists:
            successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Contours successfully saved.'; 
            if exist(outFN,'file')
                msg = 'Saving these changes will overwrite previous changes.';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFN, '-struct','tmpStruct');  
                        ShowSuccessMsg(fig, 'Success', successMsg , successMsgWaitTime);  
                    case 'Save as new'
                        [file, p] = uiputfile(outFN,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFN = fullfile(p, file);  
                        save(outFN, '-struct','tmpStruct'); 
                        ShowSuccessMsg(fig, 'Success', successMsg , successMsgWaitTime);  
                    case 'Cancel',  return;
                end %switch selection
            else 
                % if file doesn't exist:
                save(outFN, '-struct','tmpStruct'); 
                ShowSuccessMsg(fig, 'Success', successMsg , successMsgWaitTime);  
            end %if exist(outFN,'file')
             
            function c = rm3rdCol(c)
                % Remove the 3rd column in anchors 
                if ~isempty(c),  c = c(:,1:2); end
            end % function c = rm3rdCol(c)   
            function ShowSuccessMsg(fig, title, msg, waitTimeSec)
                    d = uiprogressdlg(fig,'Title', title, 'Message',msg,'value', 1);
                    pause(waitTimeSec);
                    close(d)
            end
       end %function Export2Mat
        %%
        function ExportCropPara(fig, outFN, crop_para)
            successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Cropping parameters successfully saved.'; 
            % If output file exists:
            if exist(outFN,'file')
                msg = 'Cropping parameter file exists. Overwrite?';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFN, 'crop_para');  
                        ShowSuccessMsg(fig, 'Success', successMsg, successMsgWaitTime)
                    case 'Save as new'
                        [file, p] = uiputfile(outFN,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFN = fullfile(p, file);   save(outFN, 'crop_para');  
                        ShowSuccessMsg(fig, 'Success', successMsg, successMsgWaitTime)
                    case 'Cancel',  return;
                end %switch selection
                return
            else 
                 save(outFN, 'crop_para'); 
                 ShowSuccessMsg(fig, 'Success', successMsg, successMsgWaitTime)
            end %if exist(outFN,'file')
            function ShowSuccessMsg(fig, title, msg, waitTimeSec)
                    d = uiprogressdlg(fig,'Title', title, 'Message',msg,'value', 1);
                    pause(waitTimeSec);
                    close(d)
            end
        end %  function ExportCropPara
        %%
        function hg = UpdateMaskBoundary(mask, hg)
            nPts = 200;  b = bwboundaries(mask);   b=interpLine_simple(b{1}, nPts, 'makima');   bx = b(:,2); by = b(:,1); 
            for i = 1:2,   h = hg.Children(i); clearpoints(h); addpoints(h, bx, by);  end
            drawnow
            function OutputLine = interpLine_simple(InputLine, density, method)
                %  Simplified version of interpLine;  {See also interpLine.m} InputLine: n (points) x m (dimenstions) data points;  OutputLine: 'density' x m (dimenstions) data points
                d = sqrt(sum(diff(InputLine).^2,2)); cumDist = [0; cumsum(d)]; lineLength = cumDist(end); newCumDist = linspace(0, lineLength, density); OutputLine = interp1(cumDist, InputLine, newCumDist, method);
            end %function [OutputLine, lineLength1] =interpLine_simple
        end %function hg = UpdateMaskBoundary(mask, hg)
        %
        function handle = updatePlottedCropRect(handle, p2)
            % Update the 'XData', and 'YData' in the handle of cropping rectangle
            % p1, p2 : [x,y] coordinates;  Save [p1; p2] coordinates to  'UserData' of the plot handle
            p1p2 = handle.UserData;  p1 = p1p2(1,:);
            x = [p1(1), p1(1), p2(1), p2(1), p1(1)]; 
            y = [p1(2), p2(2), p2(2), p1(2), p1(2)];
            handle.XData = x;  handle.YData = y;
        end % updateCropRect(h, p2)
%      %
        function hg = DisplayMaskBoundary(ax, mask, color1, color2, edgealpha)
            if nargin<3 || isempty(color1), color1 = 'w'; end
            if nargin<4 || isempty(color2), color2 = 'b'; end
            if nargin<5 || isempty(edgealpha), edgealpha = 0.3; end
            nPts = 200; hg = hggroup(ax);
            b = bwboundaries(mask); 
            b = interpLine_simple(b{1}, nPts, 'makima');
            x = b(:,2); y = b(:,1); 
            h1 = animatedline(ax,x, y, 'linewidth', 2, 'Marker', 'none', 'color', color1,'Parent',hg); h1.Color(4)= edgealpha; 
            h2 = animatedline(ax, x, y, 'linewidth', 1, 'Marker', 'none', 'color', color2,'Parent',hg); h2.Color(4)= edgealpha; 
            function OutputLine = interpLine_simple(InputLine, density, method)
                %  Simplified version of interpLine;  {See also interpLine.m} InputLine: n (points) x m (dimenstions) data points;  OutputLine: 'density' x m (dimenstions) data points
                d = sqrt(sum(diff(InputLine).^2,2)); cumDist = [0; cumsum(d)]; lineLength = cumDist(end); newCumDist = linspace(0, lineLength, density); OutputLine = interp1(cumDist, InputLine, newCumDist, method);
            end %function [OutputLine, lineLength1] =interpLine_simple
        end % function hg = DisplayMaskBoundary
        %
        function [mask, bw] = GenMaskFromAnchors(anchors, sigma, w, h)
            % w : image width; h : image height; anchors : [x, y] coordinates
            % anchors must be sorted before calling this function
            nPts = 200;  %anchors = sortAnchors(anchors);
            xy = interpLine_simple(anchors,nPts, 'makima');%
            % Downsample then upsample to increase speed:
            s = 10; % downsample scale
            xy1 = xy /s; w1 = round(w /s); h1 = round(h /s);
            mask1 = xy2mask(xy1(:,1), xy1(:,2),  w1, h1, sigma); 
            mask2 = imresize(mask1, [h, w]);  mask = imgaussfilt(mask2,3); bw = mask>0.5;
            
            function mask = xy2mask(x, y,  w, h, sigma)
                % Convert [x,y] coordinates on an image to a mask with the same size of the image, using Gaussian kernels.
                % INPUT:  x : n x 1 x-axis coordinates;  y : n x 1 y-axis coordinates;  w : width;   h : height;  sigma : pixel to calculate Gaussian kernel.; OUTPUT:  mask:  h x w (0~1) mask 
                mask = zeros(h,w); sigSq2 = 2*sigma^2;
                for i =1:w,  for j = 1:h, intensity = sum(exp(-1 * (((i-x).^2 + (j-y).^2) ./ sigSq2)));  mask(j,i) = intensity; end; end
            end  %function mask = xy2mask(x, y,  w, h, sigma)
            function out = interpLine_simple(in, nOut, method) %in:nIn x mDims; out:nOut x mDims
                cumD = [0; cumsum(sqrt(sum(diff(in).^2,2)))];  out = interp1(cumD, in, linspace(0, cumD(end), nOut), method);
            end %function out =interpLine_simple
        end % function [mask, bw] = GenMaskFromAnchors  
        %
        function crop_rect = get_rect_from_plot_handle(h)
            % 'crop_rect' : [xmin ymin width height]  cropping rectangle  
            x = h.XData;  y = h.YData; p1x = min(x); p1y = min(y); p2x = max(x); p2y = max(y);  crop_rect = round([p1x, p1y, p2x-p1x+1, p2y-p1y+1]);
        end % get_rect_from_plot_handle
        %
        function [anchors, selectedID] = AddOrSelectAnchor(anchors, pt, dist_epsilon)
            % anchors: n x 3 [x,y, isUser] coordinates of anchor points.
            % pt: [x,y] coordinates of clicked location.
            % dist_epsilon: distance tolerance to determine whether the current click coincides with existing anchors
            % If the clicked point 'pt' coincides with an existing point in 'anchors',
            % then the sequence number of the clicked point is returned as
            % 'selectedID', and the same 'anchors' will be returned. 
            selectedID = []; pt1 = [pt, 1]; % the 3rd element denotes user input
            if nargin<3, dist_epsilon = 3; end
            if isempty(anchors), anchors = pt1; return; end
            d = sqrt(sum((anchors(:,1:2)-pt).^2,2));
            [d, I] = min(d); 
            if d < dist_epsilon, selectedID = I; return; end
            anchors = [anchors; pt1];
        end % function [anchors, selectedID] =AddOrSelectAnchor(anchors, pt, dist_epsilon) 
        %
        function h = plotRect(ax, p1, p2, varargin)
            % plot a rectangle from 2 corners: p1, p2 : [x,y] coordinates;  Save [p1; p2] coordinates to  'UserData' of the plot handle
            x = [p1(1), p1(1), p2(1), p2(1), p1(1)];  y = [p1(2), p2(2), p2(2), p1(2), p1(2)]; h = plot(ax, x,y, varargin{:});  set(h, 'UserData', [p1; p2]);
        end %function h = plotRect(ax, p1, p2, varargin)
        %
        function sortedAnchors = sortAnchors(anchors, ifAscend)
            % Sort the anchor points (x,y coordinates) on ultrasound tongue contours. 
            % 'anchors' :  n x {x, y + other meta information} 
            % ' ifAscend'  set to true ->  sort points from left to right {default}
            %                     false ->  sort points from right to left
            %  Weirong Chen    Feb-5-2020
            if nargin < 2 || isempty(ifAscend), ifAscend = true; end
            if isempty(anchors), sortedAnchors = [];return; end
            x  = anchors(:,1); y = anchors(:,2); n = numel(x);
            sortedAnchors = NaN(size(anchors)); tmpAnchors = anchors;
            [~,Ind]=max(y); % find lowest anchor point on image (***max in y)
            pt = anchors(Ind,:); tmpAnchors(Ind,:) = []; sortedAnchors(1,:) = pt;
            for i = 2:n
                d = sqrt(sum((tmpAnchors(:,1:2)-pt(:,1:2)).^2,2));
                [~,Ind] = min(d); pt = tmpAnchors(Ind,:);  sortedAnchors(i,:) = pt; tmpAnchors(Ind,:) = []; 
            end
            %% Set the direction of sorting:
            if ifAscend
                if  sortedAnchors(1,1) > sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            else
                if  sortedAnchors(1,1) < sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            end
        end %function sortedAnchors = sortAnchors(anchors, ifAscend)
        %
        function in = ifWithinPos(pt, pos)
            % check if the (x,y) position is within the region defined by 'pos' : left bottom width height
            x = pt(1); y = pt(2); [xv, yv] = pos2xy(pos); in = inpolygon(x,y, xv, yv);
                function [xv,yv]=pos2xy(p)
                    % Converting positional values 'pos' (left bottom width height) into 4 pairs of x-y coordinates.
                    % The output x-y pairs starts from lower left corner counterclockwisely. 
                    x1 = p(1); x2 = x1+p(3); x3 = x2; x4 = x1; y1 = p(2); y2 = y1; y3 = y1+p(4); y4 = y3;
                    xv = [x1; x2; x3; x4]; yv = [y1; y2; y3; y4];
                end %function [x,y]=pos2xy
        end %function in = ifWithinPos(pt, pos)  
        %
        function var=load1var(matfilename, varname)
            % Load 1 variable from a .mat file
            % If 'varname' is empty, load the first variable in the file.
            if ~exist(matfilename,'file'), var = []; return; end
            varlist = who('-file', matfilename);
            if nargin < 2 || isempty(varname), varname = varlist{1}; end
            ix = ismember(varlist, varname);
            if ~any(ix), var = []; return; end
            var = load(matfilename, varname); 
            %var = eval(['var.',varname,';']);	 %#ok<*NASGU>
            var = var.(varname);
        end % end of function var=load1var(matfilename, varname)
        %
        function yes = hasfield(struct, fieldname)
            yes = any(ismember( fieldnames(struct), fieldname));
        end % function x = hasfield(s, f)
        %
        function [OutputLine, lineLength] = interpLine(InputLine, density, method)
            %  Interpolation for a line.
            % InputLine: n (points) x m (dimenstions) data points
            % OutputLine: 'density' x m (dimenstions) data points
            % {'density' > n} 
            % 'method': interpolation method. default value = 'spline';
            % W. Chen   20JUL2021
            if nargin<3, method='spline';end; if nargin<2, density=50; end
            if isempty(InputLine), OutputLine = []; lineLength = []; return; end
            InputLine = unique(InputLine, 'rows', 'stable'); n=size(InputLine,1);
            if n == 1, OutputLine = repmat(InputLine, density,1);return;end
            cumDist = [0; cumsum(sqrt(sum(diff(InputLine).^2,2)))]; % cumulative distances along the line
            OutputLine = interp1(cumDist, InputLine,linspace(0, cumDist(end), density), method);
            d1 = sqrt(sum(diff(OutputLine).^2,2)); newCumDist1 = [0; cumsum(d1)]; lineLength = newCumDist1(end);
        end %function [OutputLine, lineLength1] =interpLine
        %
        function [s, sr, Dur] = ImportAudio_simple(input, resampleRate)
            if nargin<2 || resampleRate==0,  resampleRate = []; end
            [s,sr] = audioread(input); if size(s,2)>1, s = mean(s(:,1:2),2); end 
            s = (s - mean(s)); s = s ./ (max(abs(s)) + (max(abs(s))*0.001)); Dur = length(s) / sr; 
            if ~isempty(resampleRate),  s = resample(s,resampleRate,sr); sr = resampleRate; end
        end % function ImportAudio_simple
        %%
        function [TextGridStruct,tierNames,TierStartLineNum, IsPointTier]= ReadTextGrid(tgFName, whichTier)
        % This function reads TextGrid file and returns a struct of TextGrid.
        % INPUT: 
        %      'tgFName' :  TextGrid filename.
        %      'whichTier' :  Specify which tier will be parsed and returned. Leave blank to output all tiers.  
        % Output : 
        %   'TextGridStruct' : TextGrid structure with the following format: 
        %       TextGrid(i).NAME : tier name
        %       TextGrid(i).segs :
        %            n Intervals x 2 ([StartTime EndTime]) matrix, if  this tier is interval tier; 
        %            n Intervals x 1 ([Time]) array, if  this tier is point tier; 
        %       TextGrid(i).labs=labs : n Intervals x 1 cell array
        %       TextGrid(i).IsPointTier= 0 : if this tier is interval tier; 
        %                                         1 : if this tier is point tier; 
        % 
        % Wei-rong Chen  Apr-19-2016
        % Update:  Aug-1-2018:  Fix the issue of double quotation marks. 
        %                      double quotation " is stored as duplication: ""
        %                      in Praat TextGrid internally. 
        %                Oct-2021: Added tier selection
        %                Mar-2022: Embeded supporting functions within the main function. 
        
        [~,~,e]=fileparts(tgFName); 
        if isempty(e), tgFName=[tgFName '.TextGrid'];end
        if ~exist(tgFName, 'file'), error('File NOT exist!');end
        
        [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(tgFName); 
        [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines);
        
        if nargin < 2 || isempty(whichTier)
            tierInd = 1:numel(tierNames);
        elseif isnumeric(whichTier)
            tierInd = whichTier;
        elseif ischar(whichTier) 
            tierInd = find(ismember(tierNames, whichTier));
        elseif iscell(whichTier)
            j = 1;
            for i = 1:numel(whichTier)
                k = find(ismember(tierNames, whichTier{i}));
                if isempty(k),  continue; end
                tierInd(j) = k;  j = j+1;
            end
        else 
            tierInd = 1:numel(tierNames);
        end
        % Check if 'whichTier' doesn't exist in the TextGrid
        totalTierInds = 1:numel(tierNames);
        if isempty(tierInd) || any(~ismember(tierInd, totalTierInds))
            error('Tier NOT exist!');
        end
        k = 1; 
        for i=tierInd
            tierName=tierNames{i};
            isPoint = IsPointTier(i); tierStart = TierStartLineNum(i);
            [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart);
            TextGridStruct(k).NAME=tierName;
            TextGridStruct(k).segs=segs; %#ok<*AGROW>
            TextGridStruct(k).labs=labs;
            TextGridStruct(k).IsPointTier=isPoint;
            k = k+1;
        end
        %% Supporting sub-functions:
                %% 
                function [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart)
                    nSegs = str2double(lines{tierStart+3}(regexp(lines{tierStart+3},'\d'):end));
                    M = 4 - isShort - isPoint;
                    StartLineNum = tierStart + 4;  EndLineNum = nSegs*M + StartLineNum - 1;
                    nIntervals = (EndLineNum - StartLineNum + 1) / M;
                    items = reshape(lines(StartLineNum:EndLineNum),M,nIntervals)';
                    labs = items(:,M);
                    kk = cellfun(@strfind, labs,repmat({'"'}, length(labs),1), 'UniformOutput',false);
                    idx1 = num2cell(cellfun(@(x) x(1), kk)+1); idx2 = num2cell(cellfun(@(x) x(end), kk)-1);
                    labs = cellfun(@(x, a,b) x(a:b), labs, idx1, idx2, 'UniformOutput',false);
                    for ilab = 1:numel(labs)
                        lab = labs{ilab};  labs{ilab} = strrep(lab, '""', '"');
                    end
                    lines1 = items(:,2-isShort:(M-1));
                    segs = cellfun(@str2double,regexprep(lines1, '[^0-9.]', ''));
                end %ParseOneTier
                %%
                function [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(fName)
                    encoding = DetectTextGridEncoding(fName);
                    try
                        lines = txt2cell(fName, encoding);
                    catch
                        error('error attempting to load from %s', fName);
                    end
                    lines=DetectStrainedQuotationSybol(lines);
                    % format checking
                    if length(lines)<15 || isempty(strfind(lines{1},'ooTextFile')) || isempty(strfind(lines{2},'"TextGrid"')) || isempty(strfind(lines{6},'exists'))    
                        error('%s : unrecognized file format', fName);
                    end
                    [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(lines);
                end %ReadValidateDataProc
                %%
                function [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines)
                % get tier names and types
                    pointTierNames = {};pointTiers=[];
                    intTierNames = {};intTiers =[];
                    
	                ind1 = find(~cellfun(@isempty,regexp(lines,'TextTier')));
                    if ~isempty(ind1)
		                pointTiers = ind1 + 1;
                        TierNameLines = lines(pointTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        pointTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    
	                ind1 = find(~cellfun(@isempty,regexp(lines,'IntervalTier')));
                    if ~isempty(ind1)
		                intTiers = ind1 + 1;
                        TierNameLines = lines(intTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        intTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    tierNames = [pointTierNames;intTierNames]; 
                    TierStartLineNum = [pointTiers;intTiers]; 
                    IsPointTier = [true(size(pointTiers)); false(size(intTiers))]; 
                    [~, TierOrder]=sort(TierStartLineNum);
                    tierNames = tierNames(TierOrder); 
                    TierStartLineNum = TierStartLineNum(TierOrder);
                    IsPointTier = IsPointTier(TierOrder);
                end %GetTierNamesAndTypes
                %%
                function [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(TGlineCells)
                try
                    metaData = TGlineCells([4 5 7]);
                    idx1 = ~isempty(strfind(lower(metaData{1}),'xmin')); %#ok<*STREMP>
                    idx2 = ~isempty(strfind(lower(metaData{2}),'xmax'));
                    idx3 = ~isempty(strfind(lower(metaData{3}),'size'));
                    if idx1 && idx2 && idx3
                        isShort = 0;
                        data =str2double(regexprep(metaData,'(XMIN|XMAX|SIZE|xmin|xmax|size|=| )',''));
                    elseif ~idx1 && ~idx2 && ~idx3
                        isShort = 1;
                        data = str2num(char(metaData)); %#ok<ST2NM>
                %         data = cellfun(@str2double, metaData);
                    else 
                        error('%s has unrecognized file format', fName);
                    end
                catch
	                error('%s has unrecognized file format', fName);
                end
                startTime = data(1); totalDur = data(2); nTiers = data(3);
                end % end of DetectTextGridCellsLongOrShort
                
                function outTextGridLines=DetectStrainedQuotationSybol(TextGridLines)
                    outTextGridLines = TextGridLines;
                    ToDelete=[];
                    for iii = 1:length(TextGridLines)-1
                        thisLine = TextGridLines{iii};
                        nextLine = TextGridLines{iii+1};
                        thisLine = strrep(thisLine,' ','');
                        nextLine = strrep(nextLine,' ','');
                        if strcmp(thisLine,'text="') && strcmp(nextLine(end),'"')
                            ToDelete = [ToDelete iii+1]; 
                            outTextGridLines{iii} = [TextGridLines{iii} TextGridLines{iii+1}];
                        end
                    end
                    outTextGridLines(ToDelete) = [];
                end %DetectStrainedQuotationSybol2
                %%
                function encoding = DetectTextGridEncoding(TextGridFName)
                % Detect the text encoding method of a PRAAT .TextGrid file.
                % Usage: encoding = DetectTextGridEncoding(TextGridFName)
                    [~,~,ext]=fileparts(TextGridFName);
                    if isempty(ext), TextGridFName=[TextGridFName '.TextGrid'];end
                    encodings{1}='UTF-8'; encodings{2}='UTF-16BE'; encodings{3}='UTF-16LE';
                    encodingWeight=NaN*zeros(1,length(encodings));
                    wid='MATLAB:iofun:UnsupportedEncoding';
                    warning('off',wid);
                    for jjj=1:length(encodings)
                        fid = fopen(TextGridFName, 'r', 'l', encodings{jjj});
                        S = fscanf(fid, '%c'); fclose(fid);
                        out = strfind(S, 'Text'); encodingWeight(jjj)=length(out);
                    end
                    [~,idx]=max(encodingWeight);
                    encoding=encodings{idx};
                    warning('on',wid);
                end %DetectTextGridEncoding
                %%
                function lines = txt2cell(filename, encoding)
                    % Read text file into cell array. Each line is stored in one cell in a cell array.
                    % Requires MATLAB R2016b or newer. 
                    % W. Chen   APR-24-2021
                    if ~exist(filename, 'file'), lines = {}; fprintf('txt2cell: file not found.\n'); return; end
                    permission = 'r'; % read file in text mode. See 'help fopen'.
                    machinefmt = 'n'; % reading or writing in bytes or bits. 'n' = 'native' {default}
                    if nargin < 2 || isempty(encoding)
                        % if encoding is not specified, fopen will automatically detect the characterset set. 
                        fid = fopen(filename,permission, machinefmt);
                    else
                        fid = fopen(filename,permission, machinefmt, encoding);
                    end
                    s = fscanf(fid, '%c'); fclose(fid);
                    % if the first character is BOM, then remove it:
                    if unicode2native(s(1))==26, s = s(2:end);end 
                    % 'splitlines' is a new funciton since R2016b, which handles different EOLs.
                    lines = splitlines(s); 
                end  % end of  txt2cell
                %%
        end %ReadTextGrid()
              
        %%
    end %methods (Access = public, Static=true)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initialize(app);
            defaultmodelname = 'DpEdg_*.mat'; 
            modelFNameMask = fullfile(app.appPath, defaultmodelname);
            fl = getfilelist(modelFNameMask);
            if ~isempty(fl)
                modelFName = fullfile(app.appPath, fl{1});
                if exist(modelFName, 'file')
                    loadModel(app, modelFName);
                end
            end

            %% Default video for testing -- delete this part later:
%             myloadvideo(app, 'video01.avi'); dispFrame(app, 1);
            function filelist = getfilelist(mask)
                FLs = dir(mask); filelist = {FLs.name}';
            end
        end

        % Menu selected function: Mn_LoadVideo
        function Bn_LoadVideoPushed(app, event)
            [fname, selectionSuccess] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); LoadVideoActivateApp(app, fname);  dispFrame(app, 1); 
            close(UIprogressDialog_handle)
        end

        % Button pushed function: Bn_FirstFrame, Bn_GoTo, Bn_LastFrame, 
        % ...and 4 other components
        function Bn_ChangeFrameButtonPushed(app, event)
            %% Update NextNFrame and PreviousNFrame
%             app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
%             app.Bn_GoTo.UserData = app.Ed_GoToN.Value - app.Ed_Frame.Value;
            %% Get UserData from calling button handle for +k frames
            k = event.Source.UserData;
            frame = app.Ed_Frame.Value + k; % 'event.Source.UserData': move forward by how many frames
            if frame < 1, frame = 1;end
            if frame > app.nFrames, frame = app.nFrames;end
%             if frame < 1 || frame > app.nFrames, return; end
            dispFrame(app, frame); updatePlotAnchors(app);   
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~app.active, return; end
%             dist_epsilon = app.Ax0.XLim(2)/100; % distance tolerance to determine whether the current click coincides with existing anchors
            [x,y]=update_axes_cursor_loc(app);
            if app.flag_SelectCropRectWindowButtonDown
                p2 =[x,y]; app.handle_cropRect=app.updatePlottedCropRect(app.handle_cropRect, p2);
            end
             %% If an anchor is selected & Sw_ClickAnchor is 'On':
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ~isempty(app.selectedAnchorID) && ~app.flag_SelectCropRectWindowButtonDown
                frame = app.Ed_Frame.Value;
                [x,y] = axes_cursor_loc(app); %pt = [x,y];
                anchors = app.ANCHORS{frame}; 
                anchors(app.selectedAnchorID, 1:2) = [x,y];
                anchors(app.selectedAnchorID, 3) =1; 
                xy = app.interpLine(anchors, app.nXYpoints,'makima');
                app.ANCHORS{frame} = anchors; app.XY{frame} = xy; 
                updatePlotAnchors(app);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1_AudioAll.Position; % Axis for AudioAll
            dist_epsilon = app.Ax0.XLim(2)/100; % distance tolerance to determine whether the current click coincides with existing anchors
            if ~app.active, return; end 
            app.selectedAnchorID = [];

           %UIFigure.Pointer is supported after R2020a (ver9.8)
           if ~verLessThan('matlab','9.8') 
               set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
           end            
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %#ok<NASGU> %current x,y positions in Ax0.
            t_audio = app.Ax1_AudioAll.CurrentPoint(1,1); % Cursor time in audio
            
            %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                % If SelectCropRectWindowButton is Down : 
                if app.flag_SelectCropRectWindowButtonDown
                    app.Msg1.Value = app.defaultMsg; 
                    app.flag_SelectCropRectWindowButtonDown = false;
                    app.flag_SelectCropRect = false; hold(app.Ax0,'on');
                    app=do_crop(app); app.Sw_ClickAnchor.Value = 'On';
                    app.Bn_CropButton.Value = 0;
                    return
                end
            
                % If Sw_ClickAnchor is 'On':
                if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ~app.flag_SelectCropRectWindowButtonDown
                    rect = app.crop_rect; frame = app.Ed_Frame.Value; mouseButton = app.UIFigure.SelectionType;
                    [x,y] = axes_cursor_loc(app); pt = [x,y];
                    if ~app.ifWithinPos(pt, rect), return; end % if the clicked pt is not within crop_rect, then skip it. 
                    anchors = app.ANCHORS{frame}; 
                    [anchors, selectedID] = app.AddOrSelectAnchor(anchors, pt, dist_epsilon);
                    % if left click and no existing anchor at this point, then add anchor:
                    % if right click on existing anchor, delete the selected anchor:
                    if ~isempty(selectedID) && strcmpi(mouseButton, 'alt') 
                        anchors(selectedID,:)=[]; 
                    end
                    anchors = app.sortAnchors(anchors); xy = app.interpLine(anchors(:,1:2), app.nXYpoints, 'makima'); 
                    app.ANCHORS{frame} = anchors; app.XY{frame} = xy; 
                    updatePlotAnchors(app);    
                    return
                end
            end %
            %% --- IF cursor is within Ax_AudioAll:
            if  app.ifWithinPos(fig_pt, pos_Ax1) && ~isempty(app.AUDIO)
                frame = floor(t_audio * app.vObj.FrameRate) + 1; 
                if isgraphics(app.AudioCursorHandle)
                    app.AudioCursorHandle.XData = [t_audio, t_audio]; 
                end
                dispFrame(app, frame); updatePlotAnchors(app);   
            end
        end

        % Value changed function: Bn_CropButton
        function Bn_SelectCropRectButtonPushed(app, event)
            if ~app.active, app.Bn_CropButton.Value = 0; return; end
            if ~verLessThan('matlab','9.8') 
               set(app.UIFigure, 'pointer','crosshair'); % Set cursor to crosshair
           end  
            if ~app.Bn_CropButton.Value, return; end
            if ~isempty(app.handle_cropRect) && app.Bn_CropButton.Value
                delete(app.handle_cropRect); app.handle_cropRect=[]; 
            end
            app=do_uncrop(app); app.flag_SelectCropRect = true;
            app.Msg1.Value = 'Click the image and drag to make a rectangle to crop...';
            app.Sw_ClickAnchor.Value = 'Off';
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~app.active, return; end
            dist_epsilon = app.Ax0.XLim(2)/100; % distance tolerance to determine whether the current click coincides with existing anchors
            %% 'SelectCropRect' pushed:
            if app.flag_SelectCropRect
                app.flag_SelectCropRectWindowButtonDown = true;
                [x,y] = axes_cursor_loc(app); hold(app.Ax0,'on')
                if app.ifInvert
                    rect_color = 'r'; 
                else
                    rect_color = 'y';
                end
                app.handle_cropRect = app.plotRect(app.Ax0, [x,y], [x,y], 'marker', 'o','color', rect_color, 'linestyle', ':', 'linewidth', 1);
                app.flag_SelectCropRect = false;
                app.Msg1.Value = 'Drag the point to make a rectangle....';
            end
            %%
            %% If Sw_ClickAnchor is 'On':
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ~app.flag_SelectCropRectWindowButtonDown
                frame = app.Ed_Frame.Value; mouseButton = app.UIFigure.SelectionType;
                [x,y] = axes_cursor_loc(app); pt = [x,y];
                if ~isempty(app.ANCHORS)
                    anchors = app.ANCHORS{frame}; 
                    [anchors, selectedID] = app.AddOrSelectAnchor(anchors, pt, dist_epsilon);
                    % if left click on existing anchor at this point, then drag this anchor (>UIFigureWindowButtonMotion):
                    if ~isempty(selectedID) && strcmpi(mouseButton, 'normal') 
                        app.selectedAnchorID = selectedID;
                    end
                else 
                    app.selectedAnchorID = [];
                end
            end
        end

        % Button pushed function: Bn_Uncrop
        function Bn_UncropButtonPushed(app, event)
            if ~app.active, return; end
            app.Bn_CropButton.Value = 0; app.flag_SelectCropRect = false;
            app.flag_SelectCropRectWindowButtonDown = false;
            app.Msg1.Value = app.defaultMsg; 
            do_uncrop(app); app.Sw_ClickAnchor.Value = 'On';
           %UIFigure.Pointer is supported after R2020a (ver9.8)
           if ~verLessThan('matlab','9.8') 
               set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
           end
        end

        % Value changed function: Sw_ClickAnchor
        function Sw_ClickAnchorValueChanged(app, event)
%             value = app.Sw_ClickAnchor.Value;
            if app.flag_SelectCropRect || app.flag_SelectCropRectWindowButtonDown
                app.Sw_ClickAnchor.Value = 'Off';
            end
        end

        % Value changed function: Slid_AnchorSize
        function Slid_AnchorSizeValueChanged(app, event)
            if ~app.active, return;end
            app.anchor_size = app.Slid_AnchorSize.Value;
            updatePlotAnchors(app); 
        end

        % Value changed function: Slid_AnchorAlpha
        function Slid_AnchorAlphaValueChanged(app, event)
             if ~app.active, return;end
            app.anchor_alpha = app.Slid_AnchorAlpha.Value/100; %Slider range:0:100
            updatePlotAnchors(app);         
        end

        % Button pushed function: Bn_DelAnchors
        function Bn_DelAnchorsButtonPushed(app, event)
            if ~app.active, return; end
            frame = app.Ed_Frame.Value; 
            app.ANCHORS{frame}=[]; app.XY{frame}=[]; app.ENERGY{frame}=[]; app.METHOD{frame}=[]; 
            if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
            updatePlotAnchors(app);  
        end

        % Value changed function: Sw_ifInvert
        function Sw_ifInvertValueChanged(app, event)
            if ~app.active, return; end
            if strcmpi(app.Sw_ifInvert.Value, 'Invert'), app.ifInvert = true; else, app.ifInvert = false; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);    
        end

        % Value changed function: Sw_ifGray
        function Sw_ifGrayValueChanged(app, event)
            if ~app.active, return; end
            if strcmpi(app.Sw_ifGray.Value, 'Gray'), app.ifGray = true; else, app.ifGray = false; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   
        end

        % Callback function: Bn_ApplyCurrentFrame, Mn_ApplyCurrentFrame
        function Bn_ApplyCurrentFramePushed(app, event)
            if ~app.active
                return; 
            end
            if isempty(app.net)
                uialert(app.UIFigure, 'No NN model loaded! Use "File"->"Load model".', 'Error', 'icon', 'info'); 
                return;
            end
            i=app.Ed_Frame.Value; 
            if app.Ck_NN.Value, method = 'NN'; elseif app.Ck_Snake.Value, method = 'NNSnake'; end
            %%
            [anchors, energy, xy] = detectOneFrame(app, app.img, method);
            if isempty(anchors), app.Msg1.Value = 'No contour detected.'; return; end
            %%
            app.ANCHORS{i} = anchors; app.XY{i} = xy; app.ENERGY{i} = energy; app.METHOD{i} = method; 
            updatePlotAnchors(app);
        end

        % Callback function: Bn_Track, Mn_Track
        function Bn_TrackPushed(app, event)
            
            app.Bn_StopTrack.Value = false;
            if ~app.active
                 app.Bn_Track.Value = false;
                return; 
            end
            if isempty(app.net)
                uialert(app.UIFigure, 'No NN model loaded! Use "File"->"Load model".', 'Error', 'icon', 'info'); 
                app.Bn_Track.Value=false; % Release track button.
                return;
            end
            app.Bn_Track.Value =true;
            frame = app.Ed_Frame.Value;
            for i = frame:app.nFrames
                pause (0.02);
                im= read(app.vObj,i); 
                if app.Ck_NN.Value, method = 'NN'; elseif app.Ck_Snake.Value, method = 'NNSnake'; end
                [anchors, energy, xy] = detectOneFrame(app, im, method);
                app.ANCHORS{i} = anchors; app.XY{i} = xy; app.ENERGY{i} = energy; app.METHOD{i} = method; 
                dispFrame(app, i);updatePlotAnchors(app); 
                if app.Bn_StopTrack.Value 
                    app.Bn_StopTrack.Value = false; %reset the button state
                    app.Bn_Track.Value = false; break
                end
            end %for i = frame:app.nFrames
            app.Bn_Track.Value = false;
        end

        % Menu selected function: Mn_ExpImgCrop, Mn_ExpImgRaw
        function Menu_ExpImg(app, event)
               videoFName = fullfile(app.vObj.Path, app.vObj.Name); 
               frame = app.Ed_Frame.Value;
               [videoPath, vFn, vExt]= fileparts(videoFName);
               ifCrop = event.Source.UserData.Crop;
               if ifCrop
                  im = imcrop(app.img, app.crop_rect); 
                  fn_suffix = '_Crop_Frame_';
               else
                   im = app.img; fn_suffix = '_Frame_';
               end
               outImgfn = [vFn, '_', strrep(vExt,'.',''), fn_suffix, num2str(frame) '.jpg'];
               outImgFName = fullfile(videoPath, outImgfn);
               imwrite(im, outImgFName);
        end

        % Menu selected function: Mn_ExpFrmAnchorsCrop, Mn_ExpFrmAnchorsOri
        function Menu_ExpFrmAnchors(app, event)
            ifCrop = event.Source.UserData.Crop;
            % Write anchors of all frame to a text file. Overwrite existing file. 
            videoFName = app.vObj.Name; videoPath = app.vObj.Path; 
            [~, vFn, ~]= fileparts(videoFName);
            if ifCrop, suffix = '_Crop'; else,  suffix = ''; end
            outAnchorsfn = [vFn, suffix '.tsv']; 
            outAnchorsFName = fullfile(videoPath, outAnchorsfn);
            fid = fopen(outAnchorsFName,'wt'); 
            if fid < 0, uialert(app.UIFigure, ['Error attempting to write ' outAnchorsFName], 'Error'); return; end
            if ifCrop,  fprintf(fid,'Crop_para(Xmin, Ymin, Width, Height) : [%.2f, %.2f, %.2f, %.2f]\n', app.crop_rect(1), app.crop_rect(2), app.crop_rect(3), app.crop_rect(4)); end
            fprintf(fid,'FRAME\tTIME\tNOTE\tPOINT\tX\tY\n'); 
            for frame = 1 : numel(app.ANCHORS)
                anchors = app.ANCHORS{frame}; % ANCHORS{iFrame} : n x 3 {x, y, isUserInput} 
                if isempty(anchors), continue;end 
                anchors = round(anchors(:,1:2), 2); 
                if ifCrop, anchors = anchors - app.crop_rect(1:2); end
                for ci = 1 : size(anchors,1)
                    x =anchors(ci,1); y=anchors(ci,2);  fprintf(fid,'%d\t%f\t%s\t%d\t%.2f\t%.2f\n', frame, (frame-1)/app.vObj.FrameRate, '', ci, x, y);
                end
            end
            fclose(fid);  uialert(app.UIFigure, ['Successfully write to ' outAnchorsFName], 'Success','icon', 'success'); 
        end

        % Menu selected function: Mn_ExportMat
        function Mn_ExportMatMenuSelected(app, event)
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, vFn);
            app.Export2Mat(app.UIFigure, outFName,app.ANCHORS, app.XY, app.ENERGY, app.METHOD, app.TIME);
        end

        % Value changed function: Ed_MaskSigma
        function Ed_MaskSigmaValueChanged(app, event)
            dispFrame(app, app.Ed_Frame.Value);
        end

        % Value changed function: Ck_UseMaskFromPreviousFrame
        function Ck_UseMaskFromPreviousFrameValueChanged(app, event)
            dispFrame(app, app.Ed_Frame.Value);
        end

        % Menu selected function: Mn_LoadContours
        function Mn_LoadContoursMenuSelected(app, event)
            if ~app.active
                 uialert(app.UIFigure,'Video not loaded yet! Load video first.','Video not loaded yet'); 
                return; 
            end
            LoadContourStructUI(app);  
        end

        % Menu selected function: Mn_LoadModel
        function Mn_LoadModelMenuSelected(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            loadModel(app, fname);
        end

        % Menu selected function: Mn_Previousframe
        function Mn_PreviousframeSelected(app, event)
            if ~app.active, return; end
            frame = app.Ed_Frame.Value -1; 
            if frame < 1, frame = 1;end
            if frame > app.nFrames, frame = app.nFrames;end

            dispFrame(app, frame); updatePlotAnchors(app);               
        end

        % Menu selected function: Mn_Nextframe
        function Mn_NextframeMenuSelected(app, event)
            if ~app.active, return; end
            frame = app.Ed_Frame.Value +1; 
            if frame < 1, frame = 1;end
            if frame > app.nFrames, frame = app.nFrames;end

            dispFrame(app, frame); updatePlotAnchors(app);  
        end

        % Menu selected function: Mn_StopTracking
        function Mn_StopTrackingMenuSelected(app, event)
            if ~app.active, return; end
            app.Bn_StopTrack.Value = true; % press StopTrack button
            app.Bn_Track.Value = false; 
        end

        % Value changed function: Ed_NextN
        function Ed_NextNValueChanged(app, event)
            %% Update NextNFrame
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; 
        end

        % Value changed function: Ed_PreviousN
        function Ed_PreviousNValueChanged(app, event)
            %% Update PreviousNFrame
            app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
        end

        % Value changed function: Ed_GoToN
        function Ed_GoToNValueChanged(app, event)
            %% Update Bn_GoTo
            app.Bn_GoTo.UserData = app.Ed_GoToN.Value - app.Ed_Frame.Value;
        end

        % Menu selected function: Mn_LoadTextGrid
        function Mn_LoadTextGridMenuSelected(app, event)
            if ~app.active, return; end
            if isempty(app.AUDIO),uialert(app.UIFigure,'No audio found.','Read error'); return; end
            % Display uigetfile dialog
            filterspec = {'*.TextGrid'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            loadTextGrid(app, fname);
            drawTextGridIntervals(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Close DeepEdge?','Quit', 'Icon','warning');
            switch selection
                case 'OK', app.delete(); case 'Cancel', return
            end
        end

        % Menu selected function: Mn_VideoInfo
        function Mn_VideoInfoMenuSelected(app, event)
            v = app.vObj; title = 'Video information';
            if isempty(v)
                msg = 'No video loaded.';
            else
                msg = sprintf('%s%s\n%s\n\nFrameRate: %.2f\nHeight: %.0f\nWidth: %.0f\nVideoFormat: %s\nDuration: %0.4f', ...
                v.Path, filesep, v.Name, v.FrameRate,v.Height, v.Width, v.VideoFormat, v.Duration);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info'); 
        end

        % Menu selected function: Mn_ModelInfo
        function Mn_ModelInfoMenuSelected(app, event)
            m = app.net; title = 'Model information';
            if isempty(m)
                msg = 'No model loaded.';
            else
                msg = sprintf('%s%s\n\n%s\n', ...
                app.modelPath, filesep, app.modelname);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info');             
        end

        % Menu selected function: Mn_AboutDeepEdge
        function Mn_AboutDeepEdgeMenuSelected(app, event)
            title = 'DeepEdge information';
            cInfo = 'Copyright (C) 2020-21 Wei-Rong Chen <chenw@haskins.yale.edu> This program is free software under GNU General Public License, version 3.This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. See the GNU General Public License for more details.'; 
            msg = sprintf('DeepEdge\nVersion: %s\nLatest Update: %s \n\n %s', ...
            app.Version, app.Update, cInfo);                
            uialert(app.UIFigure, msg, title, 'icon', 'info');              
        end

        % Menu selected function: LoadvideocontoursMenu
        function Mn_LoadVideoAndContoursMenuSelected(app, event)
            % Selection video file:
            [FileName, selectionSuccess] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            %  Load video:
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); LoadVideoActivateApp(app, FileName);  dispFrame(app, 1); 
            close(UIprogressDialog_handle)

            % Find corresponding contour file in .mat:
            [p, f, e] = fileparts(FileName);
            matFName = fullfile(p, [f '.mat']);
            % Load contours:
            if ~exist(matFName, 'file')
                uialert(app.UIFigure,'Contour (.mat) file not found! Read video only.','Contour file not found'); 
            else 
                LoadContourStructFromMatFile(app, matFName);
            end
        end

        % Menu selected function: LoadvideoTextGridMenu
        function Mn_LoadVideoAndTextGridMenuSelected(app, event)
            % Selection video file:
            [FileName, selectionSuccess] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            %  Load video:
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); LoadVideoActivateApp(app, FileName);  dispFrame(app, 1); 
            close(UIprogressDialog_handle)

            % Find corresponding TextGrid file in .TextGrid:
            [p, f, e] = fileparts(FileName);
            tgFName = fullfile(p, [f '.TextGrid']); 
            % Load TextGrid:
            if ~exist(tgFName, 'file')
                uialert(app.UIFigure, 'TextGrid file not found!','TextGrid file not found'); 
            else 
                if isempty(app.AUDIO)
                    uialert(app.UIFigure,'No audio found. Ignore TextGrid.','Read error'); 
                else
                    loadTextGrid(app, tgFName);
                    drawTextGridIntervals(app);
                end
            end   
        end

        % Menu selected function: LoadvideocontoursTextGridMenu
        function Mn_LoadVideoAndContoursAndTextGridMenuSelected(app, event)
            % Selection video file:
            [FileName, selectionSuccess] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            %  Load video:
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); LoadVideoActivateApp(app, FileName);  dispFrame(app, 1); 
            close(UIprogressDialog_handle)

            % Find corresponding contour file in .mat:
            [p, f, e] = fileparts(FileName);
            matFName = fullfile(p, [f '.mat']);
            % Load contours:
            if ~exist(matFName, 'file')
                uialert(app.UIFigure,'Contour (.mat) file not found!','Contour file not found'); 
            else 
                LoadContourStructFromMatFile(app, matFName);
            end     

            % Find corresponding TextGrid file in .TextGrid:
            [p, f, e] = fileparts(FileName);
            tgFName = fullfile(p, [f '.TextGrid']); 
            % Load TextGrid:
            if ~exist(tgFName, 'file')
                uialert(app.UIFigure, 'TextGrid file not found!','TextGrid file not found'); 
            else 
                if isempty(app.AUDIO)
                    uialert(app.UIFigure,'No audio found. Ignore TextGrid.','Read error'); 
                else
                    loadTextGrid(app, tgFName);
                    drawTextGridIntervals(app);
                end
            end   
            %%
        end

        % Menu selected function: ExportcroppingparamtersMenu
        function Mn_ExportCropParamtersMenuSelected(app, event)
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, 'crop_para.mat');
            app.ExportCropPara(app.UIFigure, outFName , app.crop_rect);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DeepEdge';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Mn_LoadVideo = uimenu(app.FileMenu);
            app.Mn_LoadVideo.MenuSelectedFcn = createCallbackFcn(app, @Bn_LoadVideoPushed, true);
            app.Mn_LoadVideo.Text = 'Load video';
            app.Mn_LoadContours = uimenu(app.FileMenu);
            app.Mn_LoadContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadContoursMenuSelected, true);
            app.Mn_LoadContours.Text = 'Load contours';
            app.Mn_LoadModel = uimenu(app.FileMenu);
            app.Mn_LoadModel.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadModelMenuSelected, true);
            app.Mn_LoadModel.Text = 'Load model';
            app.Mn_LoadTextGrid = uimenu(app.FileMenu);
            app.Mn_LoadTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadTextGridMenuSelected, true);
            app.Mn_LoadTextGrid.Text = 'Load TextGrid';
            app.LoadvideocontoursMenu = uimenu(app.FileMenu);
            app.LoadvideocontoursMenu.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoAndContoursMenuSelected, true);
            app.LoadvideocontoursMenu.Text = 'Load video + contours';
            app.LoadvideoTextGridMenu = uimenu(app.FileMenu);
            app.LoadvideoTextGridMenu.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoAndTextGridMenuSelected, true);
            app.LoadvideoTextGridMenu.Text = 'Load video + TextGrid';
            app.LoadvideocontoursTextGridMenu = uimenu(app.FileMenu);
            app.LoadvideocontoursTextGridMenu.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoAndContoursAndTextGridMenuSelected, true);
            app.LoadvideocontoursTextGridMenu.Text = 'Load video + contours + TextGrid';
            app.Mn_Export = uimenu(app.UIFigure);
            app.Mn_Export.Text = 'Export';
            app.Mn_ExportCurrentFrame = uimenu(app.Mn_Export);
            app.Mn_ExportCurrentFrame.Text = 'Current frame image';
            app.Mn_ExpImgRaw = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgRaw.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExpImg, true);
            app.Mn_ExpImgRaw.Text = 'raw';
            app.Mn_ExpImgCrop = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgCrop.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExpImg, true);
            app.Mn_ExpImgCrop.Text = 'cropped';
            app.Mn_ExpFrmAnchors = uimenu(app.Mn_Export);
            app.Mn_ExpFrmAnchors.Text = 'Export anchors to TSV';
            app.Mn_ExpFrmAnchorsOri = uimenu(app.Mn_ExpFrmAnchors);
            app.Mn_ExpFrmAnchorsOri.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExpFrmAnchors, true);
            app.Mn_ExpFrmAnchorsOri.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the original (uncropped) image.'};
            app.Mn_ExpFrmAnchorsOri.Text = 'original cartesian';
            app.Mn_ExpFrmAnchorsCrop = uimenu(app.Mn_ExpFrmAnchors);
            app.Mn_ExpFrmAnchorsCrop.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExpFrmAnchors, true);
            app.Mn_ExpFrmAnchorsCrop.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the cropped mage..'};
            app.Mn_ExpFrmAnchorsCrop.Text = 'cropped cartesian';
            app.Mn_ExportMat = uimenu(app.Mn_Export);
            app.Mn_ExportMat.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMatMenuSelected, true);
            app.Mn_ExportMat.Tooltip = {'Export results to MATLAB structure file (.mat)'};
            app.Mn_ExportMat.Text = 'Export contours to MAT file';
            app.ExportcroppingparamtersMenu = uimenu(app.Mn_Export);
            app.ExportcroppingparamtersMenu.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportCropParamtersMenuSelected, true);
            app.ExportcroppingparamtersMenu.Text = 'Export cropping paramters';
            app.ControlMenu = uimenu(app.UIFigure);
            app.ControlMenu.Text = 'Control';
            app.Mn_Previousframe = uimenu(app.ControlMenu);
            app.Mn_Previousframe.MenuSelectedFcn = createCallbackFcn(app, @Mn_PreviousframeSelected, true);
            app.Mn_Previousframe.Accelerator = 'B';
            app.Mn_Previousframe.Text = 'Previous frame';
            app.Mn_Nextframe = uimenu(app.ControlMenu);
            app.Mn_Nextframe.MenuSelectedFcn = createCallbackFcn(app, @Mn_NextframeMenuSelected, true);
            app.Mn_Nextframe.Accelerator = 'F';
            app.Mn_Nextframe.Text = 'Next frame';
            app.Mn_ApplyCurrentFrame = uimenu(app.ControlMenu);
            app.Mn_ApplyCurrentFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Mn_ApplyCurrentFrame.Accelerator = 'N';
            app.Mn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Mn_Track = uimenu(app.ControlMenu);
            app.Mn_Track.MenuSelectedFcn = createCallbackFcn(app, @Bn_TrackPushed, true);
            app.Mn_Track.Accelerator = 'T';
            app.Mn_Track.Text = 'Track';
            app.Mn_StopTracking = uimenu(app.ControlMenu);
            app.Mn_StopTracking.MenuSelectedFcn = createCallbackFcn(app, @Mn_StopTrackingMenuSelected, true);
            app.Mn_StopTracking.Accelerator = 'X';
            app.Mn_StopTracking.Text = 'Stop tracking';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Mn_VideoInfo = uimenu(app.InfoMenu);
            app.Mn_VideoInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_VideoInfoMenuSelected, true);
            app.Mn_VideoInfo.Text = 'Video Information';
            app.Mn_ModelInfo = uimenu(app.InfoMenu);
            app.Mn_ModelInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_ModelInfoMenuSelected, true);
            app.Mn_ModelInfo.Text = 'Model Information';
            app.Mn_AboutDeepEdge = uimenu(app.InfoMenu);
            app.Mn_AboutDeepEdge.MenuSelectedFcn = createCallbackFcn(app, @Mn_AboutDeepEdgeMenuSelected, true);
            app.Mn_AboutDeepEdge.Text = 'About DeepEdge';
            app.Ax0 = uiaxes(app.UIFigure);
            app.Ax0.XLim = [0 1];
            app.Ax0.ZLim = [0 1];
            app.Ax0.XTick = [];
            app.Ax0.XTickLabel = {'[ ]'};
            app.Ax0.YTick = [];
            app.Ax1_AudioAll = uiaxes(app.UIFigure);
            app.Ax1_AudioAll.XTick = [];
            app.Ax1_AudioAll.XTickLabel = '';
            app.Ax1_AudioAll.YTick = [];
            app.Ax1_AudioAll.ZTick = [];
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame#:';
            app.Ed_Frame = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Frame.Limits = [0 Inf];
            app.Ed_Frame.ValueDisplayFormat = '%.0f';
            app.Ed_Frame.Editable = 'off';
            app.Pn_FrameControl = uipanel(app.UIFigure);
            app.Bn_FirstFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_FirstFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_FirstFrame.Tooltip = {'First frame'};
            app.Bn_FirstFrame.Text = '|<';
            app.Bn_PreviousFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousFrame.Tooltip = {'Previous frame'};
            app.Bn_PreviousFrame.Text = '<';
            app.Bn_NextFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextFrame.Tooltip = {'Next Frame'};
            app.Bn_NextFrame.Text = '>';
            app.Bn_NextNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextNFrame.Tooltip = {'Jump to next N frame'};
            app.Bn_NextNFrame.Text = '>>';
            app.Bn_LastFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_LastFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_LastFrame.Tooltip = {'Last frame'};
            app.Bn_LastFrame.Text = '>|';
            app.Bn_PreviousNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousNFrame.Tooltip = {'Jump to previous N frame'};
            app.Bn_PreviousNFrame.Text = '<<';
            app.Ed_NextN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_NextN.Limits = [1 Inf];
            app.Ed_NextN.ValueDisplayFormat = '%.0f';
            app.Ed_NextN.ValueChangedFcn = createCallbackFcn(app, @Ed_NextNValueChanged, true);
            app.Ed_NextN.Tooltip = {'Jump to next N frame'};
            app.Ed_NextN.Value = 5;
            app.Ed_PreviousN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_PreviousN.Limits = [1 Inf];
            app.Ed_PreviousN.ValueDisplayFormat = '%.0f';
            app.Ed_PreviousN.ValueChangedFcn = createCallbackFcn(app, @Ed_PreviousNValueChanged, true);
            app.Ed_PreviousN.Tooltip = {'Jump to previous N frame'};
            app.Ed_PreviousN.Value = 5;
            app.Bn_GoTo = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_GoTo.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_GoTo.Tooltip = {'Jump to the Nth frame'};
            app.Bn_GoTo.Text = 'Go to';
            app.Ed_GoToN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_GoToN.Limits = [1 Inf];
            app.Ed_GoToN.ValueDisplayFormat = '%.0f';
            app.Ed_GoToN.ValueChangedFcn = createCallbackFcn(app, @Ed_GoToNValueChanged, true);
            app.Ed_GoToN.Tooltip = {'Jump to the Nth frame'};
            app.Ed_GoToN.Value = 1;
            app.Tabs = uitabgroup(app.UIFigure);
            app.Tab_Image = uitab(app.Tabs);
            app.Tab_Image.Title = 'Image';
            app.Ed_CropRect = uieditfield(app.Tab_Image, 'text');
            app.Ed_CropRect.Editable = 'off';
            app.Ed_CropRect.Enable = 'off';
            app.Sw_ifGray = uiswitch(app.Tab_Image, 'slider');
            app.Sw_ifGray.Items = {'Color', 'Gray'};
            app.Sw_ifGray.ValueChangedFcn = createCallbackFcn(app, @Sw_ifGrayValueChanged, true);
            app.Sw_ifGray.Value = 'Color';
            app.Bn_Uncrop = uibutton(app.Tab_Image, 'push');
            app.Bn_Uncrop.ButtonPushedFcn = createCallbackFcn(app, @Bn_UncropButtonPushed, true);
            app.Bn_Uncrop.Text = 'Uncrop';
            app.Bn_CropButton = uibutton(app.Tab_Image, 'state');
            app.Bn_CropButton.ValueChangedFcn = createCallbackFcn(app, @Bn_SelectCropRectButtonPushed, true);
            app.Bn_CropButton.Text = 'Crop';
            app.Sw_ifInvert = uiswitch(app.Tab_Image, 'slider');
            app.Sw_ifInvert.Items = {'Normal', 'Invert'};
            app.Sw_ifInvert.ValueChangedFcn = createCallbackFcn(app, @Sw_ifInvertValueChanged, true);
            app.Sw_ifInvert.Value = 'Normal';
            app.CropParaHelpMsg = uieditfield(app.Tab_Image, 'text');
            app.CropParaHelpMsg.Editable = 'off';
            app.CropParaHelpMsg.Enable = 'off';
            app.CropParaHelpMsg.Value = '(left, top, width, height)';
            app.AnchorTab = uitab(app.Tabs);
            app.AnchorTab.Title = 'Anchor';
            app.ClickanchorsSwitchLabel = uilabel(app.AnchorTab);
            app.ClickanchorsSwitchLabel.Text = 'Click anchors:';
            app.Sw_ClickAnchor = uiswitch(app.AnchorTab, 'slider');
            app.Sw_ClickAnchor.ValueChangedFcn = createCallbackFcn(app, @Sw_ClickAnchorValueChanged, true);
            app.Sw_ClickAnchor.Value = 'On';
            app.AnchorsizeSliderLabel = uilabel(app.AnchorTab);
            app.AnchorsizeSliderLabel.Text = 'Anchor size:';
            app.Slid_AnchorSize = uislider(app.AnchorTab);
            app.Slid_AnchorSize.Limits = [10 200];
            app.Slid_AnchorSize.MajorTicks = [];
            app.Slid_AnchorSize.ValueChangedFcn = createCallbackFcn(app, @Slid_AnchorSizeValueChanged, true);
            app.Slid_AnchorSize.MinorTicks = [];
            app.Slid_AnchorSize.Value = 10;
            app.OpacityLabel = uilabel(app.AnchorTab);
            app.OpacityLabel.Text = 'Opacity:';
            app.Slid_AnchorAlpha = uislider(app.AnchorTab);
            app.Slid_AnchorAlpha.Limits = [10 90];
            app.Slid_AnchorAlpha.MajorTicks = [];
            app.Slid_AnchorAlpha.ValueChangedFcn = createCallbackFcn(app, @Slid_AnchorAlphaValueChanged, true);
            app.Slid_AnchorAlpha.MinorTicks = [];
            app.Slid_AnchorAlpha.Value = 10;
            app.Bn_DelAnchors = uibutton(app.AnchorTab, 'push');
            app.Bn_DelAnchors.ButtonPushedFcn = createCallbackFcn(app, @Bn_DelAnchorsButtonPushed, true);
            app.Bn_DelAnchors.Tooltip = {'Delete all anchors in this frame'};
            app.Bn_DelAnchors.Text = 'Delete all anchors';
            app.UIAnchorTable = uitable(app.AnchorTab);
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.UIAnchorTable.RowName = {};
            app.DeepEdgeTab = uitab(app.Tabs);
            app.DeepEdgeTab.Title = 'DeepEdge';
            app.Bn_ApplyCurrentFrame = uibutton(app.DeepEdgeTab, 'push');
            app.Bn_ApplyCurrentFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Bn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Bn_StopTrack = uibutton(app.DeepEdgeTab, 'state');
            app.Bn_StopTrack.Text = 'Stop Tracking';
            app.Bn_Track = uibutton(app.DeepEdgeTab, 'state');
            app.Bn_Track.ValueChangedFcn = createCallbackFcn(app, @Bn_TrackPushed, true);
            app.Bn_Track.Text = 'Track';
            app.Ck_UseMaskFromPreviousFrame = uicheckbox(app.DeepEdgeTab);
            app.Ck_UseMaskFromPreviousFrame.ValueChangedFcn = createCallbackFcn(app, @Ck_UseMaskFromPreviousFrameValueChanged, true);
            app.Ck_UseMaskFromPreviousFrame.Text = 'Use mask from previous frame';
            app.MasksigmaEditFieldLabel = uilabel(app.DeepEdgeTab);
            app.MasksigmaEditFieldLabel.Text = 'Mask sigma (%)';
            app.Ed_MaskSigma = uieditfield(app.DeepEdgeTab, 'numeric');
            app.Ed_MaskSigma.Limits = [0 50];
            app.Ed_MaskSigma.ValueDisplayFormat = '%2.0g';
            app.Ed_MaskSigma.ValueChangedFcn = createCallbackFcn(app, @Ed_MaskSigmaValueChanged, true);
            app.Ed_MaskSigma.Value = 0.7;
            app.DetectMethodGroup = uibuttongroup(app.DeepEdgeTab);
            app.DetectMethodGroup.Tag = 'DeteckMethodGroup';
            app.Ck_NN = uiradiobutton(app.DetectMethodGroup);
            app.Ck_NN.Text = 'NeuralNet';
            app.Ck_Snake = uiradiobutton(app.DetectMethodGroup);
            app.Ck_Snake.Text = 'NN+SNAKE';
            app.Ck_Snake.Value = true;
            app.Ed_CursorLoc = uieditfield(app.UIFigure, 'text');
            app.Ed_CursorLoc.Editable = 'off';
            app.Ed_CursorLoc.Enable = 'off';
            app.Ed_CursorLoc.Value = '0,0';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time (s): ';
            app.Ed_Time = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Time.Limits = [0 Inf];
            app.Ed_Time.ValueDisplayFormat = '%.3f';
            app.Ed_Time.Editable = 'off';
            app.Msg1 = uitextarea(app.UIFigure);
            app.Msg1.Editable = 'off';
            app.Ed_TotalFrames = uieditfield(app.UIFigure, 'text');
            app.Ed_TotalFrames.Editable = 'off';
            app.Ed_TotalFrames.Enable = 'off';
            app.Ed_TotalTime = uieditfield(app.UIFigure, 'numeric');
            app.Ed_TotalTime.Limits = [0 Inf];
            app.Ed_TotalTime.ValueDisplayFormat = '%.3f';
            app.Ed_TotalTime.Editable = 'off';
            app.Ed_TotalTime.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepEdge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end